classdef gagui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ANTENNAPARAMETERSLabel        matlab.ui.control.Label
        GAPARAMETERSLabel             matlab.ui.control.Label
        FREQUENCYMHZEditFieldLabel    matlab.ui.control.Label
        FREQUENCYMHZEditField         matlab.ui.control.NumericEditField
        GENERATIONSEditFieldLabel     matlab.ui.control.Label
        GENERATIONSEditField          matlab.ui.control.NumericEditField
        POPULATIONSIZEEditFieldLabel  matlab.ui.control.Label
        POPULATIONSIZEEditField       matlab.ui.control.NumericEditField
        PcEditFieldLabel              matlab.ui.control.Label
        PcEditField                   matlab.ui.control.NumericEditField
        PmEditFieldLabel              matlab.ui.control.Label
        PmEditField                   matlab.ui.control.NumericEditField
        ErEditFieldLabel              matlab.ui.control.Label
        ErEditField                   matlab.ui.control.NumericEditField
        STARTGAButton                 matlab.ui.control.Button
        gadone                        matlab.ui.control.Label
    end


    methods (Access = public)
    
        
    end


    methods (Access = private)

        % Button pushed function: STARTGAButton
        function STARTGAButtonPushed(app, event)
            app.gadone.Text='running ga';
            M  = app.POPULATIONSIZEEditField.Value; % number of chromosomes (cadinate solutions)% Better if even
            MaxGen =app.GENERATIONSEditField.Value ; 
            Pc = app.PcEditField.Value;
            Pm = app.PmEditField.Value;
            Er = app.ErEditField.Value;
            Frequency=app.FREQUENCYMHZEditField.Value;
            Finalfitness=gafunc(MaxGen,M,Pc,Pm,Er,Frequency);
            app.gadone.Text = 'ga completed';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ANTENNAPARAMETERSLabel = uilabel(app.UIFigure);
            app.ANTENNAPARAMETERSLabel.Text = 'ANTENNA PARAMETERS';
            app.GAPARAMETERSLabel = uilabel(app.UIFigure);
            app.GAPARAMETERSLabel.Text = 'GA PARAMETERS';
            app.FREQUENCYMHZEditFieldLabel = uilabel(app.UIFigure);
            app.FREQUENCYMHZEditFieldLabel.Text = 'FREQUENCY(MHZ)';
            app.FREQUENCYMHZEditField = uieditfield(app.UIFigure, 'numeric');
            app.FREQUENCYMHZEditField.Value = 30;
            app.GENERATIONSEditFieldLabel = uilabel(app.UIFigure);
            app.GENERATIONSEditFieldLabel.Text = 'GENERATIONS';
            app.GENERATIONSEditField = uieditfield(app.UIFigure, 'numeric');
            app.GENERATIONSEditField.Value = 10;
            app.POPULATIONSIZEEditFieldLabel = uilabel(app.UIFigure);
            app.POPULATIONSIZEEditFieldLabel.Text = 'POPULATION SIZE';
            app.POPULATIONSIZEEditField = uieditfield(app.UIFigure, 'numeric');
            app.POPULATIONSIZEEditField.Value = 20;
            app.PcEditFieldLabel = uilabel(app.UIFigure);
            app.PcEditFieldLabel.Text = 'Pc';
            app.PcEditField = uieditfield(app.UIFigure, 'numeric');
            app.PcEditField.Value = 0.85;
            app.PmEditFieldLabel = uilabel(app.UIFigure);
            app.PmEditFieldLabel.Text = 'Pm';
            app.PmEditField = uieditfield(app.UIFigure, 'numeric');
            app.PmEditField.Value = 0.01;
            app.ErEditFieldLabel = uilabel(app.UIFigure);
            app.ErEditFieldLabel.Text = 'Er';
            app.ErEditField = uieditfield(app.UIFigure, 'numeric');
            app.ErEditField.Value = 0.05;
            app.STARTGAButton = uibutton(app.UIFigure, 'push');
            app.STARTGAButton.ButtonPushedFcn = createCallbackFcn(app, @STARTGAButtonPushed, true);
            app.STARTGAButton.Text = 'START GA';
            app.gadone = uilabel(app.UIFigure);
            app.gadone.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = gagui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end